classdef Covidapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        Button                     matlab.ui.control.Button
        CountryCodeEditFieldLabel  matlab.ui.control.Label
        ISO_CODE                   matlab.ui.control.EditField
        BrowseButton               matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        
        function results = func(app)
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
                 BrowseButtonPushed();    
            iso_code=app.ISO_CODE.Value;
            [rows,col] = size(data.iso_code);
            [logic, position]=ismember(ISOCODE,data.iso_code);
            if logic==1
new_variable = zeros(rows,1);
for i=1:length(ISOCODE)
    indices = ismember(data.iso_code,ISOCODE(i));
    new_variable(indices) = 1;
end 
data.iso_code=new_variable;
temp=data.iso_code<0.1;
data(temp,:)=[];
C_name=unique(data.location);
x=categorical(data.date(1:4:end));
y=data.total_cases(1:4:end);
plot(app.UIAxes,x,y);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename pathname]=uigetfile({'*.xlsx'},FileSelector);
            fullpathname=strcat(pathname,filename);
            name=readtable(fullpathname);
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CountryCodeEditFieldLabel = uilabel(app.UIFigure);
            app.CountryCodeEditFieldLabel.Text = 'CountryCode';
            app.ISO_CODE = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covidapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end